classdef GIWApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GIWApp_UIFigure       matlab.ui.Figure
        WorldView             matlab.ui.control.UIAxes
        EIHView               matlab.ui.control.UIAxes
        HeadView              matlab.ui.control.UIAxes
        EyeView               matlab.ui.control.UIAxes
        HorzFlip              matlab.ui.control.CheckBox
        VertFlip              matlab.ui.control.CheckBox
        TimeSliderLabel       matlab.ui.control.Label
        TimeSlider            matlab.ui.control.Slider
        TimescaleSliderLabel  matlab.ui.control.Label
        TimescaleSlider       matlab.ui.control.Slider
        DurationSLabel        matlab.ui.control.Label
        RecordTime_Field      matlab.ui.control.NumericEditField
        RecordButton          matlab.ui.control.Button
        LoadProcessData       matlab.ui.control.Button
        Labellers             matlab.ui.control.Label
        Lbr_DropDown          matlab.ui.control.DropDown
        ConfLabel             matlab.ui.control.Label
        DepthSwitchLabel      matlab.ui.control.Label
        DepthSwitch           matlab.ui.control.RockerSwitch
        LabelsLampLabel       matlab.ui.control.Label
        LabelsLamp            matlab.ui.control.Lamp
        DepthLampLabel        matlab.ui.control.Label
        DepthLamp             matlab.ui.control.Lamp
        SetPathButton         matlab.ui.control.Button
        movePast              matlab.ui.control.Button
        moveFuture            matlab.ui.control.Button
    end


    properties (Access = private)
        T % Time information
        PrIdx % Person information
        TrIdx % Trial information
        Lbr % Labeller information
        ETG % Store all EIH information
        IMU % Store all Head information
        GIW % Store all GIW infor                                                                                                                                                                                                                                                                                                                                                                                                       mation
        ZED % Store all the Depth information
        LabelData % Store LabelData
        LabelsPresent % Bool for labels
        DepthPresent % Bool for depth
        Path2Data % Path to GW Dataset
        
        DepthFr % Depth frame number
        
        % Video objects
        worldObj
        eyeObj
        depthObj
                                            
        % Image handles in the viewports
        worldIm
        eyeIm
        currTime_EIH
        currTime_Head
        por
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Initialized GIW App.')
            % User needs to set the path first
            app.LoadProcessData.Visible = false;
            app.Lbr_DropDown.Visible = false;
        end

        % Button pushed function: LoadProcessData
        function LoadProcessDataButtonPushed(app, event)
            [fName, path, ~] = uigetfile(fullfile(app.Path2Data, '*.mat'), 'Select person and trial');
            try
                load(fullfile(path, fName), 'ProcessData') % Load ProcessData
            catch
                error('Could not load ProcessData')
            end
            D = dir(fullfile(app.Path2Data, 'Labels', sprintf('PrIdx_%d_TrIdx_%d_Lbr_*.mat',...
                ProcessData.PrIdx, ProcessData.TrIdx)));
            if ~isempty(D)
                disp('Labels present!')
                app.LabelsLamp.Color = [0,1,0];
                app.LabelsPresent = 1;
                temp = cellfun(@(x) sscanf(x, 'PrIdx_%d_TrIdx_%d_Lbr_%d.mat'),...
                {D.name}, 'UniformOutput', false);
                temp = cell2mat(temp);
                app.Lbr_DropDown.ItemsData = sprintfc('%d', temp(3, :));
            else
                disp('Labels not present.')
                app.Lbr_DropDown.ItemsData = {'None'};
            end

            if ProcessData.DepthPresent
                disp('Depth present!')
                app.DepthLamp.Color = [0,1,0];
                app.DepthPresent = 1;
                app.DepthFr = ProcessData.DepthFr;
            end

            app.PrIdx = ProcessData.PrIdx; app.TrIdx = ProcessData.TrIdx;
            app.ETG = ProcessData.ETG; app.IMU = ProcessData.IMU; app.GIW = ProcessData.GIW;
            app.ZED = ProcessData.ZED;
            app.T = ProcessData.T;
            
            % Update the POR values for the screen resolution.
            % The following code may not work for older MATLAB.
            app.ETG.POR(app.ETG.POR > 1) = 1;
            app.ETG.POR(app.ETG.POR < 0) = 0;
            app.ETG.POR = app.ETG.SceneResolution.*app.ETG.POR;
            
            % Set slider range
            app.TimeSlider.Limits = [app.T(1), app.T(end)];
            
            % Plot EIH velocity traces
            plot(app.EIHView, app.T, app.ETG.EIH_vel, 'LineWidth', 1.5)
            hold(app.EIHView, 'on')
            plot(app.EIHView, app.T, app.ETG.Az_Vel, 'LineWidth', 1.5, 'Color', [1,0,0], 'LineStyle', '--')
            plot(app.EIHView, app.T, app.ETG.El_Vel, 'LineWidth', 1.5, 'Color', [0,1,0], 'LineStyle', '--')
            plot(app.EIHView, app.T, app.GIW.GIW_Vel, 'LineWidth', 1.5, 'Color', [1,0,1], 'LineStyle', '-')
            app.currTime_EIH = plot(app.EIHView, [0, 0], [0, 500], 'LineWidth', 1.5, 'Color', [0,0,0], 'LineStyle', '-');
            xlim(app.EIHView, [-0.25, +0.25])
            legend(app.EIHView, 'EIH Velocity', 'EIH Az Velocity', 'EIH El Velocity', 'GIW Velocity', ...
                'Location', 'northeast', 'AutoUpdate', 'off')
            app.EIHView.XAxisLocation = 'Origin';
            app.EIHView.YAxisLocation = 'Origin';
            
            % Plot IMU velocity traces
            plot(app.HeadView, app.T, app.IMU.Head_Vel, 'LineWidth', 1.5)
            hold(app.HeadView, 'on')
            plot(app.HeadView, app.T, app.IMU.Az_Vel, 'LineWidth', 1.5, 'Color', [1,0,0], 'LineStyle', '--')
            plot(app.HeadView, app.T, app.IMU.El_Vel, 'LineWidth', 1.5, 'Color', [0,1,0], 'LineStyle', '--')
            plot(app.HeadView, app.T, app.GIW.GIW_Vel, 'LineWidth', 1.5, 'Color', [1,0,1], 'LineStyle', '-')
            app.currTime_Head = plot(app.HeadView, [0, 0], [0, 500], 'LineWidth', 1.5, 'Color', [0,0,0], 'LineStyle', '-');
            xlim(app.HeadView, [-0.25, +0.25])
            legend(app.HeadView, 'Head Velocity', 'Head Az Velocity', 'Head El Velocity', 'GIW Velocity', ...
                'Location','northeast','AutoUpdate','off')
            app.HeadView.XAxisLocation = 'Origin';
            app.HeadView.YAxisLocation = 'Origin';
            
            % Load scene video
            app.worldObj = VideoReader(fullfile(app.Path2Data, num2str(app.PrIdx), num2str(app.TrIdx), 'world.mp4'));
            app.eyeObj = VideoReader(fullfile(app.Path2Data, num2str(app.PrIdx), num2str(app.TrIdx), 'eye0.mp4'));
            if ProcessData.DepthPresent
                app.depthObj = VideoReader(fullfile(app.Path2Data, num2str(app.PrIdx), num2str(app.TrIdx), 'Depth.mj2'));
            else
                app.depthObj = nan;
            end
            % Display first world image
            hold(app.WorldView, 'on')
            if strcmp(app.DepthSwitch.Value, 'Off') || ~app.DepthPresent
                app.worldIm = imshow(readFrame(app.worldObj), 'Parent', app.WorldView);
            else
                I = repmat(normalizeImage(readFrame(app.depthObj), 'uint8'), [1,1,3]);
                app.worldIm = imshow(I, 'Parent', app.WorldView);
            end
            app.eyeIm = imshow(readFrame(app.eyeObj), 'Parent', app.EyeView);
            
            % Define the cross hair
            app.por = scatter(app.ETG.POR(1, 1), app.ETG.POR(1, 2), 60, [1, 0, 0], ...
                'filled', 'Parent', app.WorldView);
            hold(app.WorldView, 'off')
            drawnow;
        end

        % Value changed function: Lbr_DropDown
        function Lbr_DropDownValueChanged(app, event)
            app.Lbr = str2double(app.Lbr_DropDown.Value);
            % Load LabelData
            S = load(fullfile(app.Path2Data, 'Labels', ...
                sprintf('PrIdx_%d_TrIdx_%d_Lbr_%d.mat', app.PrIdx, app.TrIdx, app.Lbr)));
            app.LabelData = S.LabelData;
            % Draw patches
            if length(app.EIHView.Children) > 5
                % Remove previous labels
                delete(app.EIHView.Children(1:end-5))
                delete(app.EIHView.Children(1:end-5))
            end
            DrawPatches(app.EIHView, app.LabelData.LabelStruct, 500)
            DrawPatches(app.HeadView, app.LabelData.LabelStruct, 500)
            
        end

        % Button pushed function: SetPathButton
        function SetPathButtonPushed(app, event)
            if exist(fullfile(pwd, 'path.json'), 'file')
                disp('Found path file. Loading ...')
                txt = fscanf(fopen('path.json', 'rb'), '%s');
                path_struct = jsondecode(txt);
                app.Path2Data = path_struct.path2data;
            else
                disp('path file not found. Manual load.')
                app.Path2Data = uigetdir(pwd);
                disp('Warning. Please set the path file.')
            end
            % Path set!
            app.Lbr_DropDown.Visible = true;
            app.LoadProcessData.Visible = true;
        end

        % Value changed function: DepthSwitch, HorzFlip, 
        % TimeSlider, TimescaleSlider, VertFlip
        function UpdateView(app, event)
            currentTime = app.TimeSlider.Value;
            n = findClosest(app.T(:), currentTime);
            k = ceil(max(app.ETG.EIH_vel(n), app.IMU.Head_Vel(n))/50);
            Y_limits = [-50*k, 50*k];
            X_limits = [currentTime - 0.25*app.TimescaleSlider.Value, currentTime + 0.25*app.TimescaleSlider.Value];
            axis(app.EIHView, [X_limits, Y_limits])
            axis(app.HeadView, [X_limits, Y_limits])
            
            if strcmp(app.DepthSwitch.Value, 'Off') || ~app.DepthPresent
                %I = readFrames(app.worldObj, app.ETG.SceneFrameNo(n), 'Frame');
                app.worldObj.CurrentTime = app.ETG.SceneFrameNo(n)/app.worldObj.FrameRate;
                I = readFrame(app.worldObj); % 1920X1080X3 - uint8
                I = uint8(I);
            else
                app.depthObj.CurrentTime = app.DepthFr(n)/app.worldObj.FrameRate;
                I = readFrame(app.depthObj); % 1920X1080X1 - uint16
                I = normalizeImage(I, 'uint8');
                I = repmat(I, [1,1,3]);
            end
            app.worldIm.CData = I;
            app.currTime_EIH.XData = [currentTime, currentTime];
            app.currTime_Head.XData = [currentTime, currentTime];
            app.por.XData = app.ETG.POR(n, 1);
            app.por.YData = app.ETG.POR(n, 2);
            
            % Set eye video
            app.eyeObj.CurrentTime = app.ETG.LeftEyeFrameNo(n)/app.eyeObj.FrameRate;
            I = readFrame(app.eyeObj); % 640X480X3 - uint8
            if app.HorzFlip.Value
                I = flip(I, 2);
            end
            if app.VertFlip.Value
                I = flip(I, 1);
            end
            app.eyeIm.CData = uint8(I);
            app.ConfLabel.Text = sprintf('Conf: %d', app.ETG.Confidence);
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            currentTime = app.TimeSlider.Value;
            % Record a DUR second clip video at 30Hz
            timeSteps = linspace(currentTime, currentTime + app.RecordTime_Field.Value, app.RecordTime_Field.Value*30);
            for idx = 1:length(timeSteps)
                app.TimeSlider.Value = timeSteps(idx);
                app.UpdateView()
                I = frame2im(getframe(app.GIWApp_UIFigure));
                [I_ind, color_map] = rgb2ind(I, 256);
                if idx == 1
                    imwrite(I_ind, color_map, 'GIF_op', 'gif', 'Loopcount', inf);
                else
                    imwrite(I_ind, color_map, 'GIF_op', 'gif', 'WriteMode', 'append');
                end
            end
            app.TimeSlider.Value = currentTime;
            app.UpdateView()
        end

        % Button pushed function: moveFuture
        function moveFutureButtonPushed(app, event)
            app.TimeSlider.Value = min(app.TimeSlider.Value + 0.02, app.T(end)); % Move 20 ms in the future
            app.UpdateView()
        end

        % Button pushed function: movePast
        function movePastButtonPushed(app, event)
            app.TimeSlider.Value = max(app.TimeSlider.Value - 0.02, 0); % Move 20 ms in the past
            app.UpdateView()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GIWApp_UIFigure = uifigure('Visible', 'off');
            app.GIWApp_UIFigure.Name = 'GIWApp';
            app.WorldView = uiaxes(app.GIWApp_UIFigure);
            title(app.WorldView, 'World view')
            app.EIHView = uiaxes(app.GIWApp_UIFigure);
            title(app.EIHView, 'EIH')
            xlabel(app.EIHView, 'Time (s)')
            ylabel(app.EIHView, '^\circ/s')
            app.HeadView = uiaxes(app.GIWApp_UIFigure);
            title(app.HeadView, 'Head')
            xlabel(app.HeadView, 'Time (s)')
            ylabel(app.HeadView, '^\circ/s')
            app.EyeView = uiaxes(app.GIWApp_UIFigure);
            title(app.EyeView, 'Left eye view')
            app.HorzFlip = uicheckbox(app.GIWApp_UIFigure);
            app.HorzFlip.ValueChangedFcn = createCallbackFcn(app, @UpdateView, true);
            app.HorzFlip.Text = 'Horz Flip';
            app.VertFlip = uicheckbox(app.GIWApp_UIFigure);
            app.VertFlip.ValueChangedFcn = createCallbackFcn(app, @UpdateView, true);
            app.VertFlip.Text = 'Vert Flip';
            app.TimeSliderLabel = uilabel(app.GIWApp_UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.GIWApp_UIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateView, true);
            app.TimescaleSliderLabel = uilabel(app.GIWApp_UIFigure);
            app.TimescaleSliderLabel.Text = 'Time scale';
            app.TimescaleSlider = uislider(app.GIWApp_UIFigure);
            app.TimescaleSlider.Limits = [1 5];
            app.TimescaleSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateView, true);
            app.TimescaleSlider.Value = 1;
            app.DurationSLabel = uilabel(app.GIWApp_UIFigure);
            app.DurationSLabel.Visible = 'off';
            app.DurationSLabel.Text = 'Duration (S)';
            app.RecordTime_Field = uieditfield(app.GIWApp_UIFigure, 'numeric');
            app.RecordTime_Field.Limits = [2 50];
            app.RecordTime_Field.Visible = 'off';
            app.RecordTime_Field.Value = 10;
            app.RecordButton = uibutton(app.GIWApp_UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Visible = 'off';
            app.RecordButton.Text = 'Record';
            app.LoadProcessData = uibutton(app.GIWApp_UIFigure, 'push');
            app.LoadProcessData.ButtonPushedFcn = createCallbackFcn(app, @LoadProcessDataButtonPushed, true);
            app.LoadProcessData.Text = 'Load';
            app.Labellers = uilabel(app.GIWApp_UIFigure);
            app.Labellers.Text = 'Labellers';
            app.Lbr_DropDown = uidropdown(app.GIWApp_UIFigure);
            app.Lbr_DropDown.ValueChangedFcn = createCallbackFcn(app, @Lbr_DropDownValueChanged, true);
            app.ConfLabel = uilabel(app.GIWApp_UIFigure);
            app.ConfLabel.Text = 'Conf';
            app.DepthSwitchLabel = uilabel(app.GIWApp_UIFigure);
            app.DepthSwitchLabel.Text = 'Depth';
            app.DepthSwitch = uiswitch(app.GIWApp_UIFigure, 'rocker');
            app.DepthSwitch.ValueChangedFcn = createCallbackFcn(app, @UpdateView, true);
            app.LabelsLampLabel = uilabel(app.GIWApp_UIFigure);
            app.LabelsLampLabel.Text = 'Labels';
            app.LabelsLamp = uilamp(app.GIWApp_UIFigure);
            app.DepthLampLabel = uilabel(app.GIWApp_UIFigure);
            app.DepthLampLabel.Text = 'Depth';
            app.DepthLamp = uilamp(app.GIWApp_UIFigure);
            app.SetPathButton = uibutton(app.GIWApp_UIFigure, 'push');
            app.SetPathButton.ButtonPushedFcn = createCallbackFcn(app, @SetPathButtonPushed, true);
            app.SetPathButton.Text = 'Set Path';
            app.movePast = uibutton(app.GIWApp_UIFigure, 'push');
            app.movePast.ButtonPushedFcn = createCallbackFcn(app, @movePastButtonPushed, true);
            app.movePast.Text = '<';
            app.moveFuture = uibutton(app.GIWApp_UIFigure, 'push');
            app.moveFuture.ButtonPushedFcn = createCallbackFcn(app, @moveFutureButtonPushed, true);
            app.moveFuture.Text = '>';
            % Show the figure after all components are created
            app.GIWApp_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GIWApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GIWApp_UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GIWApp_UIFigure)
        end
    end
end